classdef Xsens_APP_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ExportButton          matlab.ui.control.Button
        Tree                  matlab.ui.container.Tree
        JointDataNode         matlab.ui.container.TreeNode
        jL5S1Node             matlab.ui.container.TreeNode
        jL4L3Node             matlab.ui.container.TreeNode
        jL1T12Node            matlab.ui.container.TreeNode
        jT9T8Node             matlab.ui.container.TreeNode
        jT1C7Node             matlab.ui.container.TreeNode
        jRightT4ShoulderNode  matlab.ui.container.TreeNode
        jRightShoulderNode    matlab.ui.container.TreeNode
        jRightElbowNode       matlab.ui.container.TreeNode
        jRightWristNode       matlab.ui.container.TreeNode
        jLeftT4ShoulderNode   matlab.ui.container.TreeNode
        jLeftShoulderNode     matlab.ui.container.TreeNode
        jLeftElbowNode        matlab.ui.container.TreeNode
        jLeftWristNode        matlab.ui.container.TreeNode
        jRightHipNode         matlab.ui.container.TreeNode
        jRightKneeNode        matlab.ui.container.TreeNode
        jRightAnkleNode       matlab.ui.container.TreeNode
        jRightBallFootNode    matlab.ui.container.TreeNode
        jLeftHipNode          matlab.ui.container.TreeNode
        jLeftKneeNode         matlab.ui.container.TreeNode
        jLeftAnkleNode        matlab.ui.container.TreeNode
        jLeftBallFootNode     matlab.ui.container.TreeNode
        WalkingNode           matlab.ui.container.TreeNode
        ToeOffNode            matlab.ui.container.TreeNode
        HeelStrikeNode        matlab.ui.container.TreeNode
        ACCNode               matlab.ui.container.TreeNode
        ClonusNode            matlab.ui.container.TreeNode
        OscillationsNode      matlab.ui.container.TreeNode
        DurationNode          matlab.ui.container.TreeNode
        CountNode             matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        tree; files;str;JData; prompt; % Description
    end
    
    methods (Access = private)
        
        function LoadData(app)
          
            app.prompt = questdlg('How do you want to import your data?',...
                'GUI','Import Folder','Import File','Import File');
            
            switch app.prompt
                
                case 'Import Folder'
                
                uigetdir('C:\Users\Jessica S. Gilliam\Documents\MATLAB\ShepherdCenter\RMP\SCIMS'); % Set directory
                app.files = dir('*.mvnx');
                
                    for i = 1:length(app.files)
                        app.tree.data(i) = load_mvnx(app.files(i).name);
                    end
                    
                case 'Import File'
                
                app.files = uigetfile('*.mvnx');
                app.tree.data = load_mvnx(app.files);
                
            end
            
                     
        end
        
        
        function ExportData(app)
           
            if app.prompt 
                
                for i = 1:length(app.files)
                    for j = 1:22
                        for s = 1:23
                            Table_S = app.tree.data(i).segmentData(s).orientation;
                        end
                        Table_J = app.tree.data(i).jointData(j).jointAngle;
                    end
                                  
                       
                    y_1 = num2str(i);
                    
                    filename = 'Participant_JointData.xls';
                    writematrix(Table_J, filename,'Sheet',y_1);
                end
            else
                
                filename = 'Participant_JointData.xls';
                writematrix(app.tree.data,filename,'Sheet',y_1);
            end
        end
        
%         function Analyze(app)
            
%             promptn = input('Select Analysis Type:[JointAngle(1)] ');
%                     
%                     if promptn
%                         
%                         app.str = input('What joint are you looking at? Write specific Xsens joint name: ','s');
%                                                 
%                         C = {'jL5S1','jL4L3','jL1T12','jT9T8','jT1C7','jC1Head','jRightT4Shoulder','jRightShoulder',...
%                         'jRightElbow','jRightWrist','jLeftT4Shoulder','jLeftShoulder','jLeftElbow','jLeftWrist',...
%                         'jRightHip','jRightKnee','jRightAnkle','jRightBallFoot','jLeftHip','jLeftKnee','jLeftAnkle',...
%                         'jLeftBallFoot'};
%                     
%                         idx = strcmpi(app.str, C);
%                         [r,c] = find(idx);
%                         
%                         prompt2 = input('What Axis? X(2), Y(1), Z(0): ');
%                                                 
%                         prompt3 = input('What Participant are you analyzing? [ID num]: ');
%                         set = [1,5,6]; % this can be changed here
%                         [a,b] = find(set == prompt3);
%                         app.JData = app.tree.data(b).jointData(c).jointAngle(:,prompt2);
%                                               
%                         
%                     end
            
%         end
        
        function AnalyzeData(app)
            app.Tree.Multiselect = 'on';
            % once selected, what do we want to do next?
            % could lay out 1:22 file path to data?
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LoadData(app)
            app.Tree.Multiselect = 'on';
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            ExportData(app)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.Tree.Multiselect = 'on';
            selectedNodes = app.Tree.SelectedNodes;
            
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.JointDataNode = uitreenode(app.Tree);
            app.JointDataNode.Text = 'Joint Data';
            app.jL5S1Node = uitreenode(app.JointDataNode);
            app.jL5S1Node.Text = 'jL5S1';
            app.jL4L3Node = uitreenode(app.JointDataNode);
            app.jL4L3Node.Text = 'jL4L3';
            app.jL1T12Node = uitreenode(app.JointDataNode);
            app.jL1T12Node.Text = 'jL1T12';
            app.jT9T8Node = uitreenode(app.JointDataNode);
            app.jT9T8Node.Text = 'jT9T8';
            app.jT1C7Node = uitreenode(app.JointDataNode);
            app.jT1C7Node.Text = 'jT1C7';
            app.jRightT4ShoulderNode = uitreenode(app.JointDataNode);
            app.jRightT4ShoulderNode.Text = 'jRightT4Shoulder';
            app.jRightShoulderNode = uitreenode(app.JointDataNode);
            app.jRightShoulderNode.Text = 'jRightShoulder';
            app.jRightElbowNode = uitreenode(app.JointDataNode);
            app.jRightElbowNode.Text = 'jRightElbow';
            app.jRightWristNode = uitreenode(app.JointDataNode);
            app.jRightWristNode.Text = 'jRightWrist';
            app.jLeftT4ShoulderNode = uitreenode(app.JointDataNode);
            app.jLeftT4ShoulderNode.Text = 'jLeftT4Shoulder';
            app.jLeftShoulderNode = uitreenode(app.JointDataNode);
            app.jLeftShoulderNode.Text = 'jLeftShoulder';
            app.jLeftElbowNode = uitreenode(app.JointDataNode);
            app.jLeftElbowNode.Text = 'jLeftElbow';
            app.jLeftWristNode = uitreenode(app.JointDataNode);
            app.jLeftWristNode.Text = 'jLeftWrist';
            app.jRightHipNode = uitreenode(app.JointDataNode);
            app.jRightHipNode.Text = 'jRightHip';
            app.jRightKneeNode = uitreenode(app.JointDataNode);
            app.jRightKneeNode.Text = 'jRightKnee';
            app.jRightAnkleNode = uitreenode(app.JointDataNode);
            app.jRightAnkleNode.Text = 'jRightAnkle';
            app.jRightBallFootNode = uitreenode(app.JointDataNode);
            app.jRightBallFootNode.Text = 'jRightBallFoot';
            app.jLeftHipNode = uitreenode(app.JointDataNode);
            app.jLeftHipNode.Text = 'jLeftHip';
            app.jLeftKneeNode = uitreenode(app.JointDataNode);
            app.jLeftKneeNode.Text = 'jLeftKnee';
            app.jLeftAnkleNode = uitreenode(app.JointDataNode);
            app.jLeftAnkleNode.Text = 'jLeftAnkle';
            app.jLeftBallFootNode = uitreenode(app.JointDataNode);
            app.jLeftBallFootNode.Text = 'jLeftBallFoot';
            app.WalkingNode = uitreenode(app.Tree);
            app.WalkingNode.Text = 'Walking';
            app.ToeOffNode = uitreenode(app.WalkingNode);
            app.ToeOffNode.Text = 'Toe Off';
            app.HeelStrikeNode = uitreenode(app.WalkingNode);
            app.HeelStrikeNode.Text = 'Heel Strike';
            app.ACCNode = uitreenode(app.WalkingNode);
            app.ACCNode.Text = 'ACC';
            app.ClonusNode = uitreenode(app.Tree);
            app.ClonusNode.Text = 'Clonus';
            app.OscillationsNode = uitreenode(app.ClonusNode);
            app.OscillationsNode.Text = 'Oscillations';
            app.DurationNode = uitreenode(app.ClonusNode);
            app.DurationNode.Text = 'Duration';
            app.CountNode = uitreenode(app.ClonusNode);
            app.CountNode.Text = 'Count';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xsens_APP_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end